classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Switch_2                       matlab.ui.control.Switch
        Switch                         matlab.ui.control.Switch
        TabGroup5                      matlab.ui.container.TabGroup
        AllFilesTab                    matlab.ui.container.Tab
        AllFilesListBox                matlab.ui.control.ListBox
        LastUsedTab                    matlab.ui.container.Tab
        LastUsedList                   matlab.ui.control.ListBox
        TabGroup4                      matlab.ui.container.TabGroup
        PropiedadesTab                 matlab.ui.container.Tab
        Seal2Panel                     matlab.ui.container.Panel
        Label_Potencia2                matlab.ui.control.Label
        PotenciaLabel_2                matlab.ui.control.Label
        Label_Energia2                 matlab.ui.control.Label
        Label_Fs2                      matlab.ui.control.Label
        EnergiaLabel_2                 matlab.ui.control.Label
        FrecuenciademuestreoLabel_2    matlab.ui.control.Label
        Label_N_Muestras2              matlab.ui.control.Label
        NumerodemuestrasLabel_2        matlab.ui.control.Label
        Label_Duracion2                matlab.ui.control.Label
        DuracionLabel_2                matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        TrascribirButton_2             matlab.ui.control.Button
        Seal1Panel                     matlab.ui.container.Panel
        Label_Potencia1                matlab.ui.control.Label
        PotenciaLabel                  matlab.ui.control.Label
        Label_Energia1                 matlab.ui.control.Label
        Label_Fs1                      matlab.ui.control.Label
        EnergiaLabel                   matlab.ui.control.Label
        FrecuenciademuestreoLabel      matlab.ui.control.Label
        Label_N_Muestras1              matlab.ui.control.Label
        NumerodemuestrasLabel          matlab.ui.control.Label
        Label_Duracion1                matlab.ui.control.Label
        DuracionLabel                  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        TrascribirButton               matlab.ui.control.Button
        PianoTab                       matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        GrabarTab                      matlab.ui.container.Tab
        OperarPanel                    matlab.ui.container.Panel
        GuardarButton                  matlab.ui.control.Button
        InvertirButton                 matlab.ui.control.Button
        LEditField                     matlab.ui.control.NumericEditField
        LEditFieldLabel                matlab.ui.control.Label
        MEditField                     matlab.ui.control.NumericEditField
        MEditFieldLabel                matlab.ui.control.Label
        InterpolarButton               matlab.ui.control.Button
        DiezmarButton                  matlab.ui.control.Button
        Button_13                      matlab.ui.control.Button
        Button_12                      matlab.ui.control.Button
        GenerarPanel                   matlab.ui.container.Panel
        FsHzEditField                  matlab.ui.control.NumericEditField
        FsHzEditFieldLabel             matlab.ui.control.Label
        DuracionsEditField             matlab.ui.control.NumericEditField
        DuracionsEditFieldLabel        matlab.ui.control.Label
        AmplitudEditField              matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel         matlab.ui.control.Label
        FrecuenciaHzEditField          matlab.ui.control.NumericEditField
        FrecuenciaHzEditFieldLabel     matlab.ui.control.Label
        SealDropDown                   matlab.ui.control.DropDown
        SealDropDownLabel              matlab.ui.control.Label
        GrabarPanel                    matlab.ui.container.Panel
        Button_11                      matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        ReproducirPanel                matlab.ui.container.Panel
        Button_10                      matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        FiltarTab                      matlab.ui.container.Tab
        FiltrosIIRPanel                matlab.ui.container.Panel
        FrecuenciadeCorteInfHzEditField  matlab.ui.control.NumericEditField
        FrecuenciadeCorteInfHzEditFieldLabel  matlab.ui.control.Label
        FormadelfiltroDropDown         matlab.ui.control.DropDown
        FormadelfiltroDropDownLabel    matlab.ui.control.Label
        OrdenEditField                 matlab.ui.control.NumericEditField
        OrdenEditFieldLabel            matlab.ui.control.Label
        FrecuenciadeCorteSupHzEditField  matlab.ui.control.NumericEditField
        FrecuenciadeCorteSupHzEditFieldLabel  matlab.ui.control.Label
        FrecuenciadeMuestreoHzEditField  matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoHzEditFieldLabel  matlab.ui.control.Label
        TipodefiltroDropDown           matlab.ui.control.DropDown
        TipodefiltroDropDownLabel      matlab.ui.control.Label
        RuidoTab                       matlab.ui.container.Tab
        GenerarRuidoPanel              matlab.ui.container.Panel
        AmplitudmaximaEditField        matlab.ui.control.NumericEditField
        AmplitudmaximaEditFieldLabel   matlab.ui.control.Label
        DesviacionSigmaEditField       matlab.ui.control.NumericEditField
        DesviacionSigmaEditFieldLabel  matlab.ui.control.Label
        RuidoDropDown                  matlab.ui.control.DropDown
        RuidoDropDownLabel             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        GraficasTab                    matlab.ui.container.Tab
        UIAxes_f2                      matlab.ui.control.UIAxes
        UIAxes_t2                      matlab.ui.control.UIAxes
        UIAxes_f1                      matlab.ui.control.UIAxes
        UIAxes_t1                      matlab.ui.control.UIAxes
        ChatBotTab                     matlab.ui.container.Tab
    end

    properties (Access = private)
        audioPlayer;
        isStopped = true;
        timer;
        lineHandle; % Referencia al objeto de la línea roja en la gráfica
        recorder; %  Objeto para almacenar el grabador de audio
        fileNames = {}; % Celda para almacenar los nombres de los archivos grabados
        frecuencia = 1000;      % Valor por inicial
        amplitud = 1;
        duracion = 1;
        fs = 44100;
        L = 1;
        M = 1;
        sig1;
        sig1_fs;
        sig1_t_max;
        sig1_name;              %Señal 1
        sig2;
        sig2_fs;
        sig2_t_max;
        sig2_name;              %Señal 2
        tipo_IIR = 'low';
        fs_IIR = 44100;     % Valor por inicial
        fc1_IRR = 20000;
        fc2_IRR = 0;
        orden_IRR = 4;
    end
    methods (Access = public)

        % Startup function
        function StartupFcn(app)
            % Definir el directorio de los archivos .wav
            directoryPath = 'audios/';  % Ruta al directorio de los archivos de audio
            % Obtener todos los archivos .wav del directorio especificado
            wavFiles = dir(fullfile(directoryPath, '*.wav'));
            % Crear una lista de las rutas completas de los archivos .wav
            wavFileNames = {wavFiles.name};
            % Actualizar la lista con los archivos del directorio actual
            app.AllFilesListBox.Items = wavFileNames; % Mostrar las rutas completas de archivos .wav en la lista
        end
        function [grafica_t, grafica_f] = grafica_vacia(app)
            opcion = app.Switch.Value;
            disp(opcion);
            switch opcion
                case 'Grafica 1'
                    grafica_t = app.UIAxes_t1;
                    grafica_f = app.UIAxes_f1;
                case 'Grafica 2'
                    grafica_t = app.UIAxes_t2;
                    grafica_f = app.UIAxes_f2;
                otherwise
                    grafica_t = app.UIAxes_t1;
                    grafica_f = app.UIAxes_f1;
            end
        end
        function plotSignal(app)
            [grafica_t, grafica_f] = grafica_vacia(app);
            if strcmp(app.Switch.Value, 'Grafica 1')
                signal = app.sig1;
                fs = app.sig1_fs;
                t_max = app.sig1_t_max;
            elseif strcmp(app.Switch.Value, 'Grafica 2')
                signal = app.sig2;
                fs = app.sig2_fs;
                t_max = app.sig2_t_max;
            else
                % Si el Switch tiene un valor inesperado
                app.TextArea.Value = 'Error: Selección de gráfica inválida.';
                return;
            end

            % Verificar que la señal no esté vacía
            if isempty(signal)
                app.TextArea.Value = 'Error: La señal seleccionada está vacía.';
                return;
            end

            if strcmp(app.Switch.Value, 'Grafica 1')
                app.Label_Duracion1.Text = [num2str(t_max),' seg'];
                app.Label_N_Muestras1.Text = num2str(length(signal));
                app.Label_Fs1.Text = [num2str(fs),' Hz'];
                app.Label_Energia1.Text = [num2str(sum(signal.^2))];
                app.Label_Potencia1.Text = [num2str((sum(signal.^2))/length(signal))];
            elseif strcmp(app.Switch.Value, 'Grafica 2')
                app.Label_Duracion2.Text = [num2str(t_max),' seg'];
                app.Label_N_Muestras2.Text = num2str(length(signal));
                app.Label_Fs2.Text = [num2str(fs),' Hz'];
                app.Label_Energia2.Text = [num2str(sum(signal.^2))];
                app.Label_Potencia2.Text = [num2str((sum(signal.^2))/length(signal))];
            else
                % Si el Switch tiene un valor inesperado
                app.TextArea.Value = 'Error: Selección de gráfica inválida.';
                return;
            end
            
            cla(grafica_f);
            cla(grafica_t);

            % Mostrar la señal en el tiempo en grafica_t
            t = linspace(0, t_max, length(signal)); % Crear el vector de tiempo
            plot(grafica_t, t, signal, 'LineWidth', 1.5);
            title(grafica_t, 'Señal en el Tiempo');
            xlabel(grafica_t, 'Tiempo (s)');
            ylabel(grafica_t, 'Amplitud');
            % Determinar el tipo de análisis (Espectrograma o FFT)
            if strcmp(app.Switch_2.Value, 'Espectrograma')
                % Generar el espectrograma
                [s, f, t, p] = spectrogram(signal, hamming(256), 128, 256, fs, 'yaxis');
                surf(grafica_f, t, f, 10*log10(p), 'EdgeColor', 'none'); % Usar surf para graficar
                axis(grafica_f, 'tight');
                view(grafica_f, 2); % Vista 2D
                colorbar(grafica_f, 'eastoutside'); % Agregar barra de color
                title(grafica_f, 'Espectrograma');
                xlabel(grafica_f, 'Tiempo (s)');
                ylabel(grafica_f, 'Frecuencia (Hz)');
                zlabel(grafica_f, 'Potencia (dB)');
            elseif strcmp(app.Switch_2.Value, 'Espectro')
                % Generar la FFT
                N = length(signal); % Número de muestras
                f = linspace(0, fs/2, floor(N/2) + 1); % Vector de frecuencias
                fft_signal = fft(signal); % Transformada de Fourier
                magnitude = abs(fft_signal(1:floor(N/2) + 1)); % Magnitud positiva
                plot(grafica_f, f, magnitude, 'LineWidth', 1.5); % Graficar en grafica_f
                title(grafica_f, 'Espectro de Frecuencia');
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, 'Magnitud');
            else
                % Si el Switch_2 tiene un valor inesperado
                app.TextArea.Value = 'Error: Selección de análisis inválida.';
                return;
            end
        end
        function updateProgress(app, reset)
            % Obtener la gráfica de tiempo activa
            [grafica_t, ~] = app.grafica_vacia();

            % Verificar si se debe reiniciar la barra
            if reset
                % Limpia cualquier línea previa
                cla(grafica_t);
                % Replotear la señal en la gráfica activa
                if strcmp(app.Switch.Value, 'Grafica 1')
                    plot(grafica_t, linspace(0, app.sig1_t_max, length(app.sig1)), app.sig1, 'b');
                elseif strcmp(app.Switch.Value, 'Grafica 2')
                    plot(grafica_t, linspace(0, app.sig2_t_max, length(app.sig2)), app.sig2, 'b');
                end
                hold(grafica_t, 'on');
                % Añadir una línea roja para el progreso
                app.lineHandle = plot(grafica_t, [0, 0], [min(app.sig1), max(app.sig1)], 'r', 'LineWidth', 1.5);
                hold(grafica_t, 'off');
            else
                % Actualizar la posición de la línea roja
                currentTime = app.audioPlayer.CurrentSample / app.audioPlayer.SampleRate;
                if isvalid(app.lineHandle)
                    app.lineHandle.XData = [currentTime, currentTime];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartupFcn();
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            fs = 44100; % Frecuencia de muestreo
            nBits = 16; % Número de bits por muestra
            nChannels = 1; % Número de canales (1 para mono)

            % Crear el objeto audiorecorder
            app.recorder = audiorecorder(fs, nBits, nChannels);

            % Iniciar la grabación
            record(app.recorder);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % Detener la grabación
            stop(app.recorder);
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = getaudiodata(app.recorder);
                app.sig1_fs = app.recorder.SampleRate;
                app.sig1_t_max = (length(app.sig1) - 1)/app.sig1_fs;
                timestamp = datetime('now', 'Format', 'HH_mm_ddMMyyyy');
                app.sig1_name = ['audios/grabacion_' char(timestamp)];
                filename = [app.sig1_name, '.wav'];
                audiowrite(filename, app.sig1, app.sig1_fs);
                plotSignal();
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = getaudiodata(app.recorder);
                app.sig2_fs = app.recorder.SampleRate;
                app.sig2_t_max = (length(app.sig2) - 1)/app.sig2_fs;
                timestamp = datetime('now', 'Format', 'HH_mm_ddMMyyyy');
                app.sig2_name = ['audios/grabacion_' char(timestamp) ];
                filename = [app.sig2_name, '.wav'];
                audiowrite(filename, app.sig2, app.sig2_fs);
                plotSignal(app);
            end

            % Actualizar la lista de nombres de archivos y la lista desplegable
            app.fileNames{end+1} = filename; % Añadir el nuevo nombre a la celda
            app.LastUsedList.Items = app.fileNames; % Actualizar la lista con los nombres

            %Actualizar la lista de ficheros .wav en el directorio actual
            app.StartupFcn();
        end

        % Double-clicked callback: LastUsedList
        function LastUsedListDoubleClicked(app, event)
            selectedFile = app.AllFilesListBox.Value;
            directoryPath = 'audios/';

            if ~isempty(selectedFile)
                % Construir la ruta completa al archivo .wav en la carpeta audios
                fullPath = fullfile(directoryPath, selectedFile);

                % Leer el archivo de audio desde la ruta completa
                [audioData, fs] = audioread(fullPath);
                opcion = app.Switch.Value;
                if strcmp(opcion, 'Grafica 1')
                    app.sig1 = audioData;
                    app.sig1_fs = fs;
                    app.sig1_t_max = (length(audioData) - 1) / app.sig1_fs;
                    app.sig1_name = erase(selectedFile, '.wav');
                elseif strcmp(opcion, 'Grafica 2')
                    app.sig2 = audioData;
                    app.sig2_fs = fs;
                    app.sig2_t_max = (length(audioData) - 1) / app.sig2_fs;
                    app.sig2_name = erase(selectedFile, '.wav');
                end
                    plotSignal(app);
            end

        end

        % Double-clicked callback: AllFilesListBox
        function AllFilesListBoxDoubleClicked(app, event)
            selectedFile = app.AllFilesListBox.Value;
            directoryPath = 'audios/';

            if ~isempty(selectedFile)
                % Construir la ruta completa al archivo .wav en la carpeta audios
                fullPath = fullfile(directoryPath, selectedFile);

                % Leer el archivo de audio desde la ruta completa
                [audioData, fs] = audioread(fullPath);
                opcion = app.Switch.Value;
                if strcmp(opcion, 'Grafica 1')
                    app.sig1 = audioData;
                    app.sig1_fs = fs;
                    app.sig1_t_max = (length(audioData) - 1) / app.sig1_fs;
                    app.sig1_name = erase(selectedFile, '.wav');
                elseif strcmp(opcion, 'Grafica 2')
                    app.sig2 = audioData;
                    app.sig2_fs = fs;
                    app.sig2_t_max = (length(audioData) - 1) / app.sig2_fs;
                    app.sig2_name = erase(selectedFile, '.wav');
                end
                    plotSignal(app);
            end


        end

        % Clicked callback: SealDropDown
        function SealDropDownClicked(app, event)
            % Almacena amplitud y frecuencia en variables locales
            [grafica_t, grafica_f] = grafica_vacia(app);
            A = app.amplitud;
            f = app.frecuencia;
            Fs = app.fs;
            t_max = app.duracion;
            y = [];
            name = '';

            % Muestra los valores de frecuencia y amplitud para verificación
            disp(['Frecuencia: ', num2str(f)]);
            disp(['Amplitud: ', num2str(A)]);

            cla(grafica_t);                % Limpia el gráfico temporal
            cla(grafica_f);                % Limpia el gráfico de frecuencia
            if Fs == 0
                return;
            end
            % Verificación de frecuencia cero
            if f == 0
                % Define el tiempo y la señal como una línea constante
                t = 0:1/Fs:t_max;
                y = A * ones(size(t));
                name = ['sig_f0_A',A];
                opcion = app.Switch.Value;
                if strcmp(opcion, 'Grafica 1')
                    app.sig1 = y;
                    app.sig1_fs = Fs;
                    app.sig1_t_max = t_max;
                    app.sig1_name = name;
                elseif strcmp(opcion, 'Grafica 2')
                    app.sig2 = y;
                    app.sig2_fs = Fs;
                    app.sig2_t_max = t_max;
                    app.sig2_name = name;
                end
                plotSignal(app);
                return;
            end

            % Obtiene el ítem seleccionado
            item = event.InteractionInformation.Item;
            item = string(item);

            % Ajuste del eje temporal según la frecuencia de la señal

            Ts = 1 / Fs;                     % Periodo de la señal
            t = 0:Ts:t_max;                % Cubre algunos ciclos para visualización adecuada
            switch item
                case "1"  % Señal Senoidal
                    name = ['seno_f',num2str(f),'_A',num2str(A)];
                    y = A * sin(2 * pi * f * t);
                case "2"  % Señal Cosenoidal
                    name = ['cos_f',num2str(f),'_A',num2str(A)];
                    y = A * cos(2 * pi * f * t);
                case "3"  % Onda Cuadrada
                    name = ['square_f',num2str(f),'_A',num2str(A)];
                    y = A * square(2 * pi * f * t);
                case "4"  % Onda Triangular
                    name = ['triangular_f',num2str(f),'_A',num2str(A)];
                    y = A * sawtooth(2 * pi * f * t, 0.5);
                case "5"  % Onda Diente de Sierra
                    name = ['sawtooth_f',num2str(f),'_A',num2str(A)];
                    y = A * sawtooth(2 * pi * f * t);
            end
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = Fs;
                app.sig1_t_max = t_max;
                app.sig1_name = name;
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = Fs;
                app.sig2_t_max = t_max;
                app.sig2_name = name;
            end
            plotSignal(app);
        end

        % Value changed function: FrecuenciaHzEditField
        function FrecuenciaHzEditFieldValueChanged(app, event)
             app.frecuencia = app.FrecuenciaHzEditField.Value;  % Guarda el valor en la propiedad
            disp(['Nueva frecuencia: ', num2str(app.frecuencia)]);  % Verifica el valor
        end

        % Value changed function: AmplitudEditField
        function AmplitudEditFieldValueChanged(app, event)
            app.amplitud = app.AmplitudEditField.Value;  % Guarda el valor en la propiedad
            disp(['Nueva amplitud: ', num2str(app.amplitud)]);  % Verifica el valor
        end

        % Value changed function: DuracionsEditField
        function DuracionsEditFieldValueChanged(app, event)
            app.duracion = app.DuracionsEditField.Value;
            disp(['Nueva Duracion: ', num2str(app.duracion)]);  % Verifica el valor
        end

        % Value changed function: FsHzEditField
        function FsHzEditFieldValueChanged(app, event)
            app.fs = app.FsHzEditField.Value;
            disp(['Nueva fs: ', num2str(app.frecuencia)]);  % Verifica el valor
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if isempty(app.sig1) || isempty(app.sig2)
                error('Las señales no pueden estar vacías.');
            end
            if iscolumn(app.sig1)
                app.sig1 = app.sig1';   % Convertir a vector fila si es necesario
            end
            if iscolumn(app.sig2)
                app.sig2 = app.sig2';   % Convertir a vector fila si es necesario
            end
            % Remuestrear la segunda señal para coincidir con la frecuencia de la primera señal
            sig2_remuestreada = resample(app.sig2, app.sig1_fs, app.sig2_fs);
            % Ajustar la duración de ambas señales (relleno con ceros si es necesario)
            if length(app.sig1) < length(sig2_remuestreada)
                app.sig1 = [app.sig1, zeros(1, length(sig2_remuestreada) - length(app.sig1))];
            elseif length(sig2_remuestreada) < length(app.sig1)
                sig2_remuestreada = [sig2_remuestreada, zeros(1, length(app.sig1) - length(sig2_remuestreada))];
            end
            % Sumar las señales
            sum_signal = app.sig1 + sig2_remuestreada;

            % Almacenar la señal sumada en la variable de salida y los parámetros
            y = sum_signal;
            fs = app.sig1_fs;
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = fs;
                app.sig1_t_max = (length(sum_signal) - 1) / fs;
                app.sig1_name = [app.sig1_name,'_plus_',app.sig2_name]; 
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = fs;
                app.sig2_t_max = (length(sum_signal) - 1) / fs;
                app.sig2_name = [app.sig2_name,'_plus_',app.sig1_name];
            end
                plotSignal(app);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % Verificar que las señales no estén vacías
            if isempty(app.sig1) || isempty(app.sig2)
                error('Las señales no pueden estar vacías.');
            end
             if iscolumn(app.sig1)
                app.sig1 = app.sig1';   % Convertir a vector fila si es necesario
            end
            if iscolumn(app.sig2)
                app.sig2 = app.sig2';   % Convertir a vector fila si es necesario
            end
            % Remuestrear la segunda señal para que coincida con la frecuencia de la primera señal
            sig2_remuestreada = resample(app.sig2, app.sig1_fs, app.sig2_fs);
            % Ajustar la duración de ambas señales (relleno con ceros si es necesario)
            if length(app.sig1) < length(sig2_remuestreada)
                app.sig1 = [app.sig1, zeros(1, length(sig2_remuestreada) - length(app.sig1))];
            elseif length(sig2_remuestreada) < length(app.sig1)
                sig2_remuestreada = [sig2_remuestreada, zeros(1, length(app.sig1) - length(sig2_remuestreada))];
            end
            % Multiplicar las señales
            mult_signal = app.sig1 .* sig2_remuestreada;
            % Almacenar la señal multiplicada en la variable de salida y los parámetros
            y = mult_signal;
            fs = app.sig1_fs;
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = fs;
                app.sig1_t_max = (length(mult_signal) - 1) / fs;
                app.sig1_name = [app.sig1_name,'_times_',app.sig2_name];
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = fs;
                app.sig2_t_max = (length(mult_signal) - 1) / fs;
                app.sig2_name = [app.sig2_name,'_times_',app.sig1_name];
            end
                plotSignal(app);
        end

        % Button pushed function: DiezmarButton
        function DiezmarButtonPushed(app, event)
            if app.M <= 0
                error('El factor de diezmo M debe ser mayor a 0');
            end
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = downsample(app.sig1, app.M);
                app.sig1_fs = app.sig1_fs / app.M;
                app.sig1_name = [app.sig1_name,'_downsample_M',num2str(app.M)];
                
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = downsample(app.sig2, app.M);
                app.sig2_fs = app.sig2_fs / app.M;
                app.sig2_name = [app.sig2_name,'_downsample_M',num2str(app.M)];
            end
                plotSignal(app);
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            app.M = app.MEditField.Value;
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            app.L = app.LEditField.Value;
        end

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
            if app.L <= 0
                error('El factor de interpolacion L debe ser mayor a 0');
            end
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = interp(app.sig1, app.L);
                app.sig1_fs = app.sig1_fs * app.L;
                app.sig1_name = [app.sig1_name,'_interpolate_L',num2str(app.L)];
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = interp(app.sig2, app.L);
                app.sig2_fs = app.sig2_fs * app.L;
                app.sig2_name = [app.sig2_name,'_interpolate_L',num2str(app.L)];
            end
                plotSignal(app);
        end

        % Button pushed function: InvertirButton
        function InvertirButtonPushed(app, event)
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = flip(app.sig1);  % Invertir la señal en lugar de diezmar
                app.sig1_name = [app.sig1_name,'_fliped'];
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = flip(app.sig2);  % Invertir la señal en lugar de diezmar
                app.sig2_name = [app.sig2_name,'_fliped'];
            end
            plotSignal(app);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                % Guardar el archivo de audio como "grabacion.wav"
                if max(abs(app.sig1)) >= 1
                    app.sig1 = app.sig1 / max(abs(app.sig1));
                end
                sig1_remuestreada = resample(app.sig1, 44100, app.sig1_fs);
                filename = ['audios/',app.sig1_name,'.wav'];
                % Guardar el archivo de audio
                audiowrite(filename, sig1_remuestreada, 44100);
            elseif strcmp(opcion, 'Grafica 2')
                % Guardar el archivo de audio como "grabacion.wav"
                if max(abs(app.sig2)) >= 1
                    app.sig2 = app.sig2 / max(abs(app.sig2));
                end
                sig2_remuestreada = resample(app.sig2, 44100, app.sig2_fs);
                filename = ['audios/',app.sig2_name,'.wav'];
                % Guardar el archivo de audio
                audiowrite(filename, sig2_remuestreada, 44100);
            end
            % Actualizar la lista de nombres de archivos y la lista desplegable
            app.fileNames{end+1} = filename; % Añadir el nuevo nombre a la celda
            app.LastUsedList.Items = app.fileNames; % Actualizar la lista con los nombres

            %Actualizar la lista de ficheros .wav en el directorio actual
            app.StartupFcn();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Verificar si se detuvo previamente
            if app.isStopped
                opcion = app.Switch.Value;
                if strcmp(opcion, 'Grafica 1')
                    app.audioPlayer = audioplayer(app.sig1, app.sig1_fs);
                elseif strcmp(opcion, 'Grafica 2')
                    app.audioPlayer = audioplayer(app.sig2, app.sig2_fs);
                end

                % Reiniciar la barra roja
                app.updateProgress(true);

                % Configurar un timer para actualizar el progreso
                app.timer = timer('ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~, ~) app.updateProgress(false));

                % Reproducir el audio y comenzar el timer
                play(app.audioPlayer);
                start(app.timer);
                app.isStopped = false;
            else
                % Reanudar la reproducción
                resume(app.audioPlayer);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            % Pausar la reproducción si está activa
            if isplaying(app.audioPlayer)
                pause(app.audioPlayer);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            % Detener la reproducción y el timer
            stop(app.audioPlayer);
            stop(app.timer);

            % Marcar como detenido
            app.isStopped = true;

            % Reiniciar la barra roja al inicio
            app.updateProgress(true);
        end

        % Button pushed function: TrascribirButton
        function TrascribirButtonPushed(app, event)
            % Guardar el archivo de audio como "grabacion.wav"
            if max(abs(app.sig1)) >= 1
                app.sig1 = app.sig1 / max(abs(app.sig1));
            end
            sig1_remuestreada = resample(app.sig1, 44100, app.sig1_fs);
            filename = ['audios/',app.sig1_name,'.wav'];
            % Guardar el archivo de audio
            audiowrite(filename, sig1_remuestreada, 44100);
            file_path = ['./',filename]
            % URL del servidor Python
            serverUrl = 'http://127.0.0.1:5000/transcribe';

            % Crear el JSON con el nombre del archivo
            data = struct('filename', file_path);

            options = weboptions('RequestMethod', 'post', 'MediaType', 'application/json', 'Timeout', 30); % Set timeout to 30 seconds
            response = webwrite(serverUrl, data, options);

            % Procesar la respuesta
            if isfield(response, 'text')
                app.TextArea.Value = response.text;
            else
                app.TextArea.Value = response.error;
            end

        end

        % Button pushed function: TrascribirButton_2
        function TrascribirButton_2Pushed(app, event)
            % Guardar el archivo de audio como "grabacion.wav"
            if max(abs(app.sig2)) >= 1
                app.sig2 = app.sig2 / max(abs(app.sig2));
            end
            sig2_remuestreada = resample(app.sig2, 44100, app.sig2_fs);
            filename = ['audios/',app.sig2_name,'.wav'];
            % Guardar el archivo de audio
            audiowrite(filename, sig2_remuestreada, 44100);
            file_path = ['./',filename];
            % URL del servidor Python
            serverUrl = 'http://127.0.0.1:5000/transcribe';

            % Crear el JSON con el nombre del archivo
            data = struct('filename', file_path);

            options = weboptions('RequestMethod', 'post', 'MediaType', 'application/json', 'Timeout', 30); % Set timeout to 30 seconds
            response = webwrite(serverUrl, data, options);

            % Procesar la respuesta
            if isfield(response, 'text')
                app.TextArea_2.Value = response.text;
            else
                app.TextArea_2.Value = response.error;
            end

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
           plotSignal(app);
        end

        % Value changed function: TipodefiltroDropDown
        function TipodefiltroDropDownValueChanged(app, event)
    % Obtener el tipo de filtro seleccionado
    value = app.TipodefiltroDropDown.Value; % Tipo de filtro: Butterworth, Chebyshev, etc.
    filterForm = app.FormadelfiltroDropDown.Value; % Forma del filtro: Paso Bajo, Paso Alto, etc.

    % Validar que las frecuencias de corte y de muestreo sean correctas
    if app.fs_IIR <= 0 || app.fc1_IRR <= 0 || (strcmp(filterForm, 'Paso Banda') || strcmp(filterForm, 'Rechaza Banda') && app.fc2_IRR <= 0)
        app.TextArea.Value = 'Error: Asegúrate de que las frecuencias de corte y la frecuencia de muestreo sean mayores que 0.';
        return;
    end

    % Calcular la frecuencia de Nyquist
    nyquist = app.fs_IIR / 2;

    % Normalizar las frecuencias de corte
    fc1_norm = app.fc1_IRR / nyquist;
    fc2_norm = app.fc2_IRR / nyquist;

    % Validar que las frecuencias estén en el rango (0, 1)
    if fc1_norm > 1 || fc2_norm > 1 || fc1_norm <= 0 || (fc2_norm <= 0 && strcmp(filterForm, 'Paso Banda'))
        app.TextArea.Value = 'Error: Las frecuencias de corte deben estar entre 0 y la mitad de la frecuencia de muestreo.';
        return;
    end

    % Validar que fc2_norm < fc1_norm en filtros pasa-banda y rechaza-banda
    if (strcmp(filterForm, 'Paso Banda') || strcmp(filterForm, 'Rechaza Banda')) && fc2_norm >= fc1_norm
        app.TextArea.Value = 'Error: La frecuencia inferior debe ser menor que la superior en filtros pasa-banda/rechaza-banda.';
        return;
    end

    % Convertir la forma del filtro a los valores aceptados por MATLAB
    switch filterForm
        case 'Paso Bajo'
            filterType = 'low';
        case 'Paso Alto'
            filterType = 'high';
        case 'Paso Banda'
            filterType = 'bandpass';
        case 'Rechaza Banda'
            filterType = 'stop';
    end

    % Generar los coeficientes del filtro según el tipo seleccionado
    try
        switch value
            case 'Butterworth'
                if strcmp(filterType, 'low') || strcmp(filterType, 'high')
                    [b, a] = butter(app.orden_IRR, fc1_norm, filterType);
                else
                    [b, a] = butter(app.orden_IRR, [fc2_norm, fc1_norm], filterType);
                end
            case 'Chebyshev tipo I'
                if strcmp(filterType, 'low') || strcmp(filterType, 'high')
                    [b, a] = cheby1(app.orden_IRR, 0.5, fc1_norm, filterType);
                else
                    [b, a] = cheby1(app.orden_IRR, 0.5, [fc2_norm, fc1_norm], filterType);
                end
            case 'Chebyshev tipo II'
                if strcmp(filterType, 'low') || strcmp(filterType, 'high')
                    [b, a] = cheby2(app.orden_IRR, 20, fc1_norm, filterType);
                else
                    [b, a] = cheby2(app.orden_IRR, 20, [fc2_norm, fc1_norm], filterType);
                end
            case 'Elíptico'
                if strcmp(filterType, 'low') || strcmp(filterType, 'high')
                    [b, a] = ellip(app.orden_IRR, 0.5, 20, fc1_norm, filterType);
                else
                    [b, a] = ellip(app.orden_IRR, 0.5, 20, [fc2_norm, fc1_norm], filterType);
                end
        end

        % Aplicar el filtro a la señal seleccionada
        opcion = app.Switch.Value;
        if strcmp(opcion, 'Grafica 1') && ~isempty(app.sig1)
            app.sig1 = filter(b, a, app.sig1);
            app.sig1_name = [app.sig1_name, '_filtered'];
        elseif strcmp(opcion, 'Grafica 2') && ~isempty(app.sig2)
            app.sig2 = filter(b, a, app.sig2);
            app.sig2_name = [app.sig2_name, '_filtered'];
        else
            app.TextArea.Value = 'Error: No hay señal cargada para filtrar.';
            return;
        end

        % Actualizar la gráfica con la señal filtrada
        plotSignal(app);

    catch ME
        % Capturar y mostrar errores
        app.TextArea.Value = ['Error al aplicar el filtro: ', ME.message];
    end
        end

        % Value changed function: FormadelfiltroDropDown
        function FormadelfiltroDropDownValueChanged(app, event)
        
        end

        % Value changed function: FrecuenciadeMuestreoHzEditField
        function FrecuenciadeMuestreoHzEditFieldValueChanged(app, event)
            app.fs_IIR = app.FrecuenciadeMuestreoHzEditField.Value;
            
        end

        % Value changed function: FrecuenciadeCorteSupHzEditField
        function FrecuenciadeCorteSupHzEditFieldValueChanged(app, event)
            app.fc1_IRR = app.FrecuenciadeCorteSupHzEditField.Value;
        end

        % Value changed function: OrdenEditField
        function OrdenEditFieldValueChanged(app, event)
            app.orden_IRR = app.OrdenEditField.Value;
        end

        % Value changed function: FrecuenciadeCorteInfHzEditField
        function FrecuenciadeCorteInfHzEditFieldValueChanged(app, event)
          app.fc2_IRR = app.FrecuenciadeCorteInfHzEditField.Value;
        end

        % Value changed function: RuidoDropDown
        function RuidoDropDownValueChanged(app, event)
    % Obtener el tipo de ruido seleccionado
    tipo_ruido = app.RuidoDropDown.Value; % Tipo de ruido desde el menú desplegable
    opcion = app.Switch.Value; % Gráfica seleccionad
    max_amplitud = app.AmplitudmaximaEditField.Value; % Amplitud máxima permitida

    % Determinar la señal activa (sig1 o sig2)
    if strcmp(opcion, 'Grafica 1')
        signal = app.sig1;
    elseif strcmp(opcion, 'Grafica 2')
        signal = app.sig2;
    else
        app.TextArea.Value = 'Error: Seleccione una gráfica válida.';
        return;
    end

    % Número de muestras de la señal
    N = length(signal);

    % Generar el ruido según el tipo seleccionado
    switch tipo_ruido
        case 'Ruido Blanco Gaussiano'
            sigma = app.DesviacionSigmaEditField.Value; % Desviación estándar
            ruido = sigma * randn(1, N);
            name ='_con_Ruido_Blanco';
        case 'Ruido Uniforme'
            ruido = (2 * rand(1, N) - 1); % Ruido uniforme en [-1, 1]
            name ='_con_Ruido_Uniforme';
        case 'Ruido Rosa'
            ruido = pinknoise(N);
            name ='_con_Ruido_Rosa';
        case 'Ruido Salt and Pepper'
            p_salt = 0.05; % Probabilidad de impulsos positivos
            p_pepper = 0.05; % Probabilidad de impulsos negativos
            ruido = zeros(1, N);
            ruido(rand(1, N) < p_salt) = 1; % Impulsos positivos
            ruido(rand(1, N) < p_pepper) = -1; % Impulsos negativos
            name ='_con_Salt_and_Pepper';
        case 'Ruido Exponencial'
            lambda = app.LambdaEditField.Value; % Tasa de decaimiento
            ruido = -log(1 - rand(1, N)) / lambda;
            name ='_con_Ruido_Exponencial';
        case 'Ruido Logarítmico'
            ruido = log(1 + rand(1, N)); % Ruido logarítmico
            name ='_con_Ruido_Logaritmico';
        otherwise
            app.TextArea.Value = 'Error: Seleccione un tipo de ruido válido.';
            return;
    end

    % Normalizar el ruido a la amplitud máxima
    ruido = ruido / max(abs(ruido)) * max_amplitud;

    % Añadir el ruido a la señal seleccionada
    signal_con_ruido = signal(:) + ruido(:);

    % Actualizar la señal activa y graficar
    if strcmp(opcion, 'Grafica 1')
        app.sig1 = signal_con_ruido;
        app.sig1_name = [app.sig1_name, name];
    elseif strcmp(opcion, 'Grafica 2')
        app.sig2 = signal_con_ruido;
        app.sig2_name = [app.sig2_name, name];
    end

    % Actualizar la gráfica
    plotSignal(app);     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficasTab = uitab(app.TabGroup);
            app.GraficasTab.Title = 'Graficas';
            app.UIAxes_t1 = uiaxes(app.GraficasTab);
            title(app.UIAxes_t1, 'Title')
            xlabel(app.UIAxes_t1, 'X')
            ylabel(app.UIAxes_t1, 'Y')
            zlabel(app.UIAxes_t1, 'Z')
            app.UIAxes_f1 = uiaxes(app.GraficasTab);
            title(app.UIAxes_f1, 'Title')
            xlabel(app.UIAxes_f1, 'X')
            ylabel(app.UIAxes_f1, 'Y')
            zlabel(app.UIAxes_f1, 'Z')
            app.UIAxes_t2 = uiaxes(app.GraficasTab);
            title(app.UIAxes_t2, 'Title')
            xlabel(app.UIAxes_t2, 'X')
            ylabel(app.UIAxes_t2, 'Y')
            zlabel(app.UIAxes_t2, 'Z')
            app.UIAxes_f2 = uiaxes(app.GraficasTab);
            title(app.UIAxes_f2, 'Title')
            xlabel(app.UIAxes_f2, 'X')
            ylabel(app.UIAxes_f2, 'Y')
            zlabel(app.UIAxes_f2, 'Z')
            app.ChatBotTab = uitab(app.TabGroup);
            app.ChatBotTab.Title = 'ChatBot';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.GrabarTab = uitab(app.TabGroup2);
            app.GrabarTab.Title = 'Grabar';
            app.ReproducirPanel = uipanel(app.GrabarTab);
            app.ReproducirPanel.Title = 'Reproducir';
            app.Button_2 = uibutton(app.ReproducirPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'img', 'b_reproducir.png');
            app.Button_2.Text = '';
            app.Button_9 = uibutton(app.ReproducirPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = fullfile(pathToMLAPP, 'img', 'b_detener.png');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.ReproducirPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = fullfile(pathToMLAPP, 'img', 'b_pausar.png');
            app.Button_10.Text = '';
            app.GrabarPanel = uipanel(app.GrabarTab);
            app.GrabarPanel.Title = 'Grabar';
            app.Button_7 = uibutton(app.GrabarPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'img', 'b_grabar.png');
            app.Button_7.Text = '';
            app.Button_11 = uibutton(app.GrabarPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = fullfile(pathToMLAPP, 'img', 'b_detener.png');
            app.Button_11.Text = '';
            app.GenerarPanel = uipanel(app.GrabarTab);
            app.GenerarPanel.Title = 'Generar';
            app.SealDropDownLabel = uilabel(app.GenerarPanel);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.GenerarPanel);
            app.SealDropDown.Items = {'Seno', 'Coseno', 'Onda Cuadrada', 'Onda Triangular', 'Diente de Sierra'};
            app.SealDropDown.ClickedFcn = createCallbackFcn(app, @SealDropDownClicked, true);
            app.SealDropDown.Value = 'Seno';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.GenerarPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.FrecuenciaHzEditField.Limits = [0 Inf];
            app.FrecuenciaHzEditField.ValueDisplayFormat = '%.0f';
            app.FrecuenciaHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciaHzEditFieldValueChanged, true);
            app.FrecuenciaHzEditField.Value = 1000;
            app.AmplitudEditFieldLabel = uilabel(app.GenerarPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.AmplitudEditField.ValueDisplayFormat = '%.0f';
            app.AmplitudEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudEditFieldValueChanged, true);
            app.AmplitudEditField.Value = 1;
            app.DuracionsEditFieldLabel = uilabel(app.GenerarPanel);
            app.DuracionsEditFieldLabel.Text = 'Duracion (s)';
            app.DuracionsEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.DuracionsEditField.ValueDisplayFormat = '%.0f';
            app.DuracionsEditField.ValueChangedFcn = createCallbackFcn(app, @DuracionsEditFieldValueChanged, true);
            app.DuracionsEditField.Value = 1;
            app.FsHzEditFieldLabel = uilabel(app.GenerarPanel);
            app.FsHzEditFieldLabel.Text = 'Fs (Hz)';
            app.FsHzEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.FsHzEditField.Limits = [0 Inf];
            app.FsHzEditField.ValueDisplayFormat = '%.0f';
            app.FsHzEditField.ValueChangedFcn = createCallbackFcn(app, @FsHzEditFieldValueChanged, true);
            app.FsHzEditField.Value = 44100;
            app.OperarPanel = uipanel(app.GrabarTab);
            app.OperarPanel.Title = 'Operar';
            app.Button_12 = uibutton(app.OperarPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Icon = fullfile(pathToMLAPP, 'img', 'b_Suma.png');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.OperarPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Icon = fullfile(pathToMLAPP, 'img', 'b_por.png');
            app.Button_13.Text = '';
            app.DiezmarButton = uibutton(app.OperarPanel, 'push');
            app.DiezmarButton.ButtonPushedFcn = createCallbackFcn(app, @DiezmarButtonPushed, true);
            app.DiezmarButton.Text = 'Diezmar';
            app.InterpolarButton = uibutton(app.OperarPanel, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Text = 'Interpolar';
            app.MEditFieldLabel = uilabel(app.OperarPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.OperarPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 1;
            app.LEditFieldLabel = uilabel(app.OperarPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.OperarPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 1;
            app.InvertirButton = uibutton(app.OperarPanel, 'push');
            app.InvertirButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirButtonPushed, true);
            app.InvertirButton.Text = 'Invertir';
            app.GuardarButton = uibutton(app.OperarPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.FiltarTab = uitab(app.TabGroup2);
            app.FiltarTab.Title = 'Filtar';
            app.FiltrosIIRPanel = uipanel(app.FiltarTab);
            app.FiltrosIIRPanel.Title = 'Filtros IIR';
            app.TipodefiltroDropDownLabel = uilabel(app.FiltrosIIRPanel);
            app.TipodefiltroDropDownLabel.Text = 'Tipo de filtro';
            app.TipodefiltroDropDown = uidropdown(app.FiltrosIIRPanel);
            app.TipodefiltroDropDown.Items = {'Butterworth', 'Chebyshev tipo I', 'Chebyshev tipo II', 'Elíptico'};
            app.TipodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDownValueChanged, true);
            app.TipodefiltroDropDown.Value = 'Butterworth';
            app.FrecuenciadeMuestreoHzEditFieldLabel = uilabel(app.FiltrosIIRPanel);
            app.FrecuenciadeMuestreoHzEditFieldLabel.Text = 'Frecuencia de Muestreo (Hz)';
            app.FrecuenciadeMuestreoHzEditField = uieditfield(app.FiltrosIIRPanel, 'numeric');
            app.FrecuenciadeMuestreoHzEditField.Limits = [0 Inf];
            app.FrecuenciadeMuestreoHzEditField.ValueDisplayFormat = '%.0f';
            app.FrecuenciadeMuestreoHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeMuestreoHzEditFieldValueChanged, true);
            app.FrecuenciadeMuestreoHzEditField.Value = 44100;
            app.FrecuenciadeCorteSupHzEditFieldLabel = uilabel(app.FiltrosIIRPanel);
            app.FrecuenciadeCorteSupHzEditFieldLabel.Text = 'Frecuencia de Corte Sup (Hz)';
            app.FrecuenciadeCorteSupHzEditField = uieditfield(app.FiltrosIIRPanel, 'numeric');
            app.FrecuenciadeCorteSupHzEditField.Limits = [0 Inf];
            app.FrecuenciadeCorteSupHzEditField.ValueDisplayFormat = '%.0f';
            app.FrecuenciadeCorteSupHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteSupHzEditFieldValueChanged, true);
            app.FrecuenciadeCorteSupHzEditField.Value = 20000;
            app.OrdenEditFieldLabel = uilabel(app.FiltrosIIRPanel);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.FiltrosIIRPanel, 'numeric');
            app.OrdenEditField.Limits = [0 Inf];
            app.OrdenEditField.ValueDisplayFormat = '%.0f';
            app.OrdenEditField.ValueChangedFcn = createCallbackFcn(app, @OrdenEditFieldValueChanged, true);
            app.OrdenEditField.Value = 4;
            app.FormadelfiltroDropDownLabel = uilabel(app.FiltrosIIRPanel);
            app.FormadelfiltroDropDownLabel.Text = 'Forma del filtro';
            app.FormadelfiltroDropDown = uidropdown(app.FiltrosIIRPanel);
            app.FormadelfiltroDropDown.Items = {'Paso Bajo', 'Paso Alto', 'Paso Banda', 'Rechaza Banda'};
            app.FormadelfiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FormadelfiltroDropDownValueChanged, true);
            app.FormadelfiltroDropDown.Value = 'Paso Bajo';
            app.FrecuenciadeCorteInfHzEditFieldLabel = uilabel(app.FiltrosIIRPanel);
            app.FrecuenciadeCorteInfHzEditFieldLabel.Text = 'Frecuencia de Corte Inf (Hz)';
            app.FrecuenciadeCorteInfHzEditField = uieditfield(app.FiltrosIIRPanel, 'numeric');
            app.FrecuenciadeCorteInfHzEditField.Limits = [0 Inf];
            app.FrecuenciadeCorteInfHzEditField.ValueDisplayFormat = '%.0f';
            app.FrecuenciadeCorteInfHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteInfHzEditFieldValueChanged, true);
            app.RuidoTab = uitab(app.TabGroup2);
            app.RuidoTab.Title = 'Ruido';
            app.GenerarRuidoPanel = uipanel(app.RuidoTab);
            app.GenerarRuidoPanel.Title = 'Generar Ruido';
            app.RuidoDropDownLabel = uilabel(app.GenerarRuidoPanel);
            app.RuidoDropDownLabel.Text = 'Ruido';
            app.RuidoDropDown = uidropdown(app.GenerarRuidoPanel);
            app.RuidoDropDown.Items = {'Ruido Blanco Gaussiano', 'Ruido Uniforme', 'Ruido Rosa', 'Ruido Salt and Pepper', 'Ruido Exponencial', 'Ruido Logarítmico'};
            app.RuidoDropDown.ValueChangedFcn = createCallbackFcn(app, @RuidoDropDownValueChanged, true);
            app.RuidoDropDown.Value = 'Ruido Blanco Gaussiano';
            app.DesviacionSigmaEditFieldLabel = uilabel(app.GenerarRuidoPanel);
            app.DesviacionSigmaEditFieldLabel.Text = 'Desviacion (Sigma)';
            app.DesviacionSigmaEditField = uieditfield(app.GenerarRuidoPanel, 'numeric');
            app.DesviacionSigmaEditField.Limits = [0 Inf];
            app.DesviacionSigmaEditField.ValueDisplayFormat = '%.3f';
            app.DesviacionSigmaEditField.Value = 0.1;
            app.AmplitudmaximaEditFieldLabel = uilabel(app.GenerarRuidoPanel);
            app.AmplitudmaximaEditFieldLabel.Text = 'Amplitud maxima';
            app.AmplitudmaximaEditField = uieditfield(app.GenerarRuidoPanel, 'numeric');
            app.AmplitudmaximaEditField.Limits = [0 Inf];
            app.AmplitudmaximaEditField.ValueDisplayFormat = '%.3f';
            app.AmplitudmaximaEditField.Value = 0.05;
            app.TabGroup4 = uitabgroup(app.UIFigure);
            app.PropiedadesTab = uitab(app.TabGroup4);
            app.PropiedadesTab.Title = 'Propiedades';
            app.Seal1Panel = uipanel(app.PropiedadesTab);
            app.Seal1Panel.Title = 'Señal 1';
            app.TrascribirButton = uibutton(app.Seal1Panel, 'push');
            app.TrascribirButton.ButtonPushedFcn = createCallbackFcn(app, @TrascribirButtonPushed, true);
            app.TrascribirButton.Text = 'Trascribir';
            app.TextArea = uitextarea(app.Seal1Panel);
            app.DuracionLabel = uilabel(app.Seal1Panel);
            app.DuracionLabel.Text = 'Duracion:';
            app.Label_Duracion1 = uilabel(app.Seal1Panel);
            app.Label_Duracion1.Text = '';
            app.NumerodemuestrasLabel = uilabel(app.Seal1Panel);
            app.NumerodemuestrasLabel.Text = 'Numero de muestras:';
            app.Label_N_Muestras1 = uilabel(app.Seal1Panel);
            app.Label_N_Muestras1.Text = '';
            app.FrecuenciademuestreoLabel = uilabel(app.Seal1Panel);
            app.FrecuenciademuestreoLabel.Text = 'Frecuencia de muestreo:';
            app.EnergiaLabel = uilabel(app.Seal1Panel);
            app.EnergiaLabel.Text = 'Energia:';
            app.Label_Fs1 = uilabel(app.Seal1Panel);
            app.Label_Fs1.Text = '';
            app.Label_Energia1 = uilabel(app.Seal1Panel);
            app.Label_Energia1.Text = '';
            app.PotenciaLabel = uilabel(app.Seal1Panel);
            app.PotenciaLabel.Text = 'Potencia:';
            app.Label_Potencia1 = uilabel(app.Seal1Panel);
            app.Label_Potencia1.Text = '';
            app.Seal2Panel = uipanel(app.PropiedadesTab);
            app.Seal2Panel.Title = 'Señal 2';
            app.TrascribirButton_2 = uibutton(app.Seal2Panel, 'push');
            app.TrascribirButton_2.ButtonPushedFcn = createCallbackFcn(app, @TrascribirButton_2Pushed, true);
            app.TrascribirButton_2.Text = 'Trascribir';
            app.TextArea_2 = uitextarea(app.Seal2Panel);
            app.DuracionLabel_2 = uilabel(app.Seal2Panel);
            app.DuracionLabel_2.Text = 'Duracion:';
            app.Label_Duracion2 = uilabel(app.Seal2Panel);
            app.Label_Duracion2.Text = '';
            app.NumerodemuestrasLabel_2 = uilabel(app.Seal2Panel);
            app.NumerodemuestrasLabel_2.Text = 'Numero de muestras:';
            app.Label_N_Muestras2 = uilabel(app.Seal2Panel);
            app.Label_N_Muestras2.Text = '';
            app.FrecuenciademuestreoLabel_2 = uilabel(app.Seal2Panel);
            app.FrecuenciademuestreoLabel_2.Text = 'Frecuencia de muestreo:';
            app.EnergiaLabel_2 = uilabel(app.Seal2Panel);
            app.EnergiaLabel_2.Text = 'Energia:';
            app.Label_Fs2 = uilabel(app.Seal2Panel);
            app.Label_Fs2.Text = '';
            app.Label_Energia2 = uilabel(app.Seal2Panel);
            app.Label_Energia2.Text = '';
            app.PotenciaLabel_2 = uilabel(app.Seal2Panel);
            app.PotenciaLabel_2.Text = 'Potencia:';
            app.Label_Potencia2 = uilabel(app.Seal2Panel);
            app.Label_Potencia2.Text = '';
            app.PianoTab = uitab(app.TabGroup4);
            app.PianoTab.Title = 'Piano';
            app.TabGroup5 = uitabgroup(app.UIFigure);
            app.AllFilesTab = uitab(app.TabGroup5);
            app.AllFilesTab.Title = 'All Files';
            app.AllFilesListBox = uilistbox(app.AllFilesTab);
            app.AllFilesListBox.Items = {};
            app.AllFilesListBox.DoubleClickedFcn = createCallbackFcn(app, @AllFilesListBoxDoubleClicked, true);
            app.AllFilesListBox.Value = {};
            app.LastUsedTab = uitab(app.TabGroup5);
            app.LastUsedTab.Title = 'Last Used';
            app.LastUsedList = uilistbox(app.LastUsedTab);
            app.LastUsedList.Items = {};
            app.LastUsedList.DoubleClickedFcn = createCallbackFcn(app, @LastUsedListDoubleClicked, true);
            app.LastUsedList.Value = {};
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Grafica 1', 'Grafica 2'};
            app.Switch.Value = 'Grafica 1';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Espectro', 'Espectrograma'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Espectro';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end